classdef plant_analysis_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI                             matlab.ui.Figure
        CreateparameterfilesButton      matlab.ui.control.Button
        ParameterDropDownLabel          matlab.ui.control.Label
        ParameterDropDown               matlab.ui.control.DropDown
        ImageHeightEditFieldLabel       matlab.ui.control.Label
        ImageHeightEditField            matlab.ui.control.NumericEditField
        RightboundLabel                 matlab.ui.control.Label
        RightboundEditField             matlab.ui.control.NumericEditField
        BelowplantboundLabel            matlab.ui.control.Label
        BottomZboundEditField           matlab.ui.control.NumericEditField
        AboveplantboundLabel            matlab.ui.control.Label
        TopZboundEditField              matlab.ui.control.NumericEditField
        TextparamEditFieldLabel         matlab.ui.control.Label
        TextparamEditField              matlab.ui.control.EditField
        GenerateparameterfilesButton    matlab.ui.control.Button
        booleanDropDownLabel            matlab.ui.control.Label
        booleanDropDown                 matlab.ui.control.DropDown
        LeftboundLabel                  matlab.ui.control.Label
        LeftboundEditField              matlab.ui.control.NumericEditField
        ViewdepthimageforboundingboxButton  matlab.ui.control.Button
        AnalyseplantsovertimeButton     matlab.ui.control.Button
        AnalyseallplantsovertimeButton  matlab.ui.control.Button
        PlotmeasurementsButton          matlab.ui.control.Button
        PlotallmeasurementsButton       matlab.ui.control.Button
        AnalysesingleplantButton        matlab.ui.control.Button
        ViewpointcloudButton            matlab.ui.control.Button
        ViewdepthimageButton            matlab.ui.control.Button
        ForegroundthresholdEditFieldLabel  matlab.ui.control.Label
        ForegroundthresholdEditField    matlab.ui.control.NumericEditField
        BackgroundthresholdEditFieldLabel  matlab.ui.control.Label
        BackgroundthresholdEditField    matlab.ui.control.NumericEditField
        CancelButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        im_height = 424;
        im_width = 512;
        im_no = 11;
        plant_axis = 'y';
        do_remove_plane = true;
        do_remove_pot = true;
        depth_thresholds = [250, 1800];
        bounding_box = [250, 1500, 1, 1500];
        save_pc = true;
        save_measurements = true;
        % TODO make background_distance a parameter
        background_distance = 2000;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
        end

        % Button pushed function: CreateparameterfilesButton
        function CreateparameterfilesButtonPushed(app, event)
            app.CreateparameterfilesButton.Visible = 'off';
            
            app.AnalyseallplantsovertimeButton.Visible = 'off';
            app.AnalyseplantsovertimeButton.Visible = 'off';            
            app.AnalysesingleplantButton.Visible = 'off';
            
            app.PlotmeasurementsButton.Visible = 'off';
            app.PlotallmeasurementsButton.Visible = 'off';
            
            app.GenerateparameterfilesButton.Visible = 'on';
            
            app.ParameterDropDown.Visible = 'on';
            app.ParameterDropDownLabel.Visible = 'on';
            
            app.ImageHeightEditField.Visible = 'on';
            app.ImageHeightEditFieldLabel.Visible = 'on';
            
            app.CancelButton.Visible = 'on';
        end

        % Button pushed function: GenerateparameterfilesButton
        function GenerateparameterfilesButtonPushed(app, event)
            folder_path = uigetdir('', 'Choose a top level folder to generate files for all its bottom level folders');
            
            drawnow;
            figure(app.GUI)
            
            end_paths = get_end_paths(folder_path, {});
            
            for i = 1:numel(end_paths)
                path = end_paths{i};
                generate_parameter_file(path, app.im_height, app.im_width, ...
                    app.im_no, app.plant_axis, app.do_remove_plane, app.do_remove_pot, ...
                    app.depth_thresholds, app.bounding_box, app.save_pc, app.save_measurements);  
            end     
            
            app.CreateparameterfilesButton.Visible = 'on';
            
            app.AnalyseallplantsovertimeButton.Visible = 'on';
            app.AnalyseplantsovertimeButton.Visible = 'on';
            app.AnalysesingleplantButton.Visible = 'on';
            
            app.PlotmeasurementsButton.Visible = 'on';
            app.PlotallmeasurementsButton.Visible = 'on';
            
            app.CancelButton.Visible = 'off';
            
            app.GenerateparameterfilesButton.Visible = 'off';
            
            app.ParameterDropDown.Visible = 'off';
            app.ParameterDropDownLabel.Visible = 'off';
            
            app.ImageHeightEditField.Visible = 'off';
            app.ImageHeightEditFieldLabel.Visible = 'off';
            
            app.ImageHeightEditField.Visible = 'off';
            app.ImageHeightEditFieldLabel.Visible = 'off';
            
            app.booleanDropDown.Visible = 'off';
            app.booleanDropDownLabel.Visible = 'off';
                
            app.TextparamEditField.Visible = 'off';
            app.TextparamEditFieldLabel.Visible = 'off';
            
            app.RightboundEditField.Visible = 'off';
            app.RightboundLabel.Visible = 'off';
            
            app.LeftboundEditField.Visible = 'off';
            app.LeftboundLabel.Visible = 'off';
            
            app.BottomZboundEditField.Visible = 'off';
            app.BelowplantboundLabel.Visible = 'off';
            
            app.TopZboundEditField.Visible = 'off';
            app.AboveplantboundLabel.Visible = 'off';
            
            app.ForegroundthresholdEditField.Visible = 'off';
            app.ForegroundthresholdEditFieldLabel.Visible = 'off';
            
            app.BackgroundthresholdEditField.Visible = 'off';
            app.BackgroundthresholdEditFieldLabel.Visible = 'off';
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CreateparameterfilesButton.Visible = 'on';
            
            app.AnalyseallplantsovertimeButton.Visible = 'on';
            app.AnalyseplantsovertimeButton.Visible = 'on';
            app.AnalysesingleplantButton.Visible = 'on';
            
            app.PlotmeasurementsButton.Visible = 'on';
            app.PlotallmeasurementsButton.Visible = 'on';
            
            app.CancelButton.Visible = 'off';
            
            app.GenerateparameterfilesButton.Visible = 'off';
            
            app.ParameterDropDown.Visible = 'off';
            app.ParameterDropDownLabel.Visible = 'off';
            
            app.ImageHeightEditField.Visible = 'off';
            app.ImageHeightEditFieldLabel.Visible = 'off';
            
            app.ImageHeightEditField.Visible = 'off';
            app.ImageHeightEditFieldLabel.Visible = 'off';
            
            app.booleanDropDown.Visible = 'off';
            app.booleanDropDownLabel.Visible = 'off';
                
            app.TextparamEditField.Visible = 'off';
            app.TextparamEditFieldLabel.Visible = 'off';
            
            app.RightboundEditField.Visible = 'off';
            app.RightboundLabel.Visible = 'off';
            
            app.LeftboundEditField.Visible = 'off';
            app.LeftboundLabel.Visible = 'off';
            
            app.BottomZboundEditField.Visible = 'off';
            app.BelowplantboundLabel.Visible = 'off';
            
            app.TopZboundEditField.Visible = 'off';
            app.AboveplantboundLabel.Visible = 'off';
            
            app.ForegroundthresholdEditField.Visible = 'off';
            app.ForegroundthresholdEditFieldLabel.Visible = 'off';
            
            app.BackgroundthresholdEditField.Visible = 'off';
            app.BackgroundthresholdEditFieldLabel.Visible = 'off';
        end

        % Value changed function: ParameterDropDown
        function ParameterDropDownValueChanged(app, event)
            value = app.ParameterDropDown.Value;       
                
            app.ImageHeightEditField.Visible = 'off';
            app.ImageHeightEditFieldLabel.Visible = 'off';
            
            app.booleanDropDown.Visible = 'off';
            app.booleanDropDownLabel.Visible = 'off';
                
            app.TextparamEditField.Visible = 'off';
            app.TextparamEditFieldLabel.Visible = 'off';
            
            app.RightboundEditField.Visible = 'off';
            app.RightboundLabel.Visible = 'off';
            
            app.LeftboundEditField.Visible = 'off';
            app.LeftboundLabel.Visible = 'off';
            
            app.BottomZboundEditField.Visible = 'off';
            app.BelowplantboundLabel.Visible = 'off';
            
            app.TopZboundEditField.Visible = 'off';
            app.AboveplantboundLabel.Visible = 'off';
            
            app.ForegroundthresholdEditField.Visible = 'off';
            app.ForegroundthresholdEditFieldLabel.Visible = 'off';
            
            app.BackgroundthresholdEditField.Visible = 'off';
            app.BackgroundthresholdEditFieldLabel.Visible = 'off';
            
            if strcmp(value, 'Vertical axis')
                app.TextparamEditFieldLabel.Text = value;
                
                app.TextparamEditField.Visible = 'on';
                app.TextparamEditFieldLabel.Visible = 'on';
                
                app.TextparamEditField.Value = app.plant_axis;
                
            elseif strcmp(value, 'Remove plane') || strcmp(value, 'Remove pot') ...
                    || strcmp(value, 'Save point cloud') || strcmp(value, 'Save measurements')
                
                app.booleanDropDownLabel.Text = value;
                
                app.booleanDropDown.Visible = 'on';
                app.booleanDropDownLabel.Visible = 'on';
                
            elseif strcmp(value, 'Bounding box')                    
                app.LeftboundEditField.Visible = 'on';
                app.LeftboundLabel.Visible = 'on';
                app.LeftboundEditField.Value = app.bounding_box(1);
                
                app.RightboundEditField.Visible = 'on';
                app.RightboundLabel.Visible = 'on';
                app.RightboundEditField.Value = app.bounding_box(2);
                
                app.BottomZboundEditField.Visible = 'on';
                app.BelowplantboundLabel.Visible = 'on';
                app.BottomZboundEditField.Value = app.bounding_box(3);
                
                app.TopZboundEditField.Visible = 'on';
                app.AboveplantboundLabel.Visible = 'on';
                app.TopZboundEditField.Value = app.bounding_box(4);
                    
            elseif strcmp(value, 'Depth thresholds')   
                app.ForegroundthresholdEditField.Visible = 'on';
                app.ForegroundthresholdEditFieldLabel.Visible = 'on';
                app.ForegroundthresholdEditField.Value = app.depth_thresholds(1);
                
                app.BackgroundthresholdEditField.Visible = 'on';
                app.BackgroundthresholdEditFieldLabel.Visible = 'on';
                app.BackgroundthresholdEditField.Value = app.depth_thresholds(2);
                
            else                    
                app.ImageHeightEditFieldLabel.Text = value;
                
                app.ImageHeightEditField.Visible = 'on';
                app.ImageHeightEditFieldLabel.Visible = 'on';
                
                if strcmp(value, 'Image Height')
                    app.ImageHeightEditField.Value = app.im_height;
                    
                elseif strcmp(value, 'Image Width')
                    app.ImageHeightEditField.Value = app.im_width;
                
                elseif strcmp(value, 'Number of views')
                    app.ImageHeightEditField.Value = app.im_no;
                end
            end
        end

        % Button pushed function: ViewdepthimageButton
        function ViewdepthimageButtonPushed(app, event)
            [file_name, file_path] = uigetfile('*.png', 'Select a depth image');
            
            drawnow;
            figure(app.GUI)
            
            depth_im = imread(fullfile(file_path, file_name));
            
            figure;
            imagesc(depth_im);
            colorbar;
        end

        % Button pushed function: ViewdepthimageforboundingboxButton
        function ViewdepthimageforboundingboxButtonPushed(app, event)
            [file_name, file_path] = uigetfile('*.png', 'Select a depth image');
            
            drawnow;
            figure(app.GUI)
            
            depth_im = imread(fullfile(file_path, file_name));

            segmented_im = segment_depth_im(depth_im, app.ForegroundthresholdEditField.Value, ...
                app.BackgroundthresholdEditField.Value);
    
            pc = depthImage2PC(segmented_im);
            
            figure;
            pcshow(pc);
            
            xlabel('X')
            ylabel('Y')
            zlabel('Z')
        end

        % Button pushed function: ViewpointcloudButton
        function ViewpointcloudButtonPushed(app, event)
            [file_name, file_path] = uigetfile('*.ply', 'Select a point cloud');
            
            drawnow;
            figure(app.GUI)
            
            pc = pcread(fullfile(file_path, file_name));
            
            figure;
            pcshow(pc);
            
            xlabel('X')
            ylabel('Y')
            zlabel('Z')
        end

        % Value changed function: ImageHeightEditField
        function ImageHeightEditFieldValueChanged(app, event)
            value = app.ImageHeightEditField.Value;
            
            if strcmp(value, 'Image Height')
                app.im_height = value;
                
            elseif strcmp(value, 'Image Width')
                app.im_width = value;
            
            elseif strcmp(value, 'Number of views')
                app.im_no = value;
            end 
        end

        % Value changed function: TextparamEditField
        function TextparamEditFieldValueChanged(app, event)
            value = app.TextparamEditField.Value;
            
            app.plant_axis = value;
        end

        % Value changed function: booleanDropDown
        function booleanDropDownValueChanged(app, event)
            value = app.booleanDropDown.Value;
            
            if strcmp(value, 'true')
                value = true;
            else
                value = false;
            end
            
            if strcmp(value, 'Remove plane')
                app.do_remove_plane = value;
                
            elseif strcmp(value, 'Remove pot')
                app.do_remove_pot = value;
                
            elseif strcmp(value, 'Save point cloud')
                app.save_pc = value;
                
            elseif trcmp(value, 'Save measurements')
                app.save_measurements = value;
            end
        end

        % Value changed function: RightboundEditField
        function RightboundEditFieldValueChanged(app, event)
            value = app.RightboundEditField.Value;
            
            app.bounding_box(2) = value;
        end

        % Value changed function: LeftboundEditField
        function LeftboundEditFieldValueChanged(app, event)
            value = app.LeftboundEditField.Value;
            
            app.bounding_box(1) = value;
        end

        % Value changed function: BottomZboundEditField
        function BottomZboundEditFieldValueChanged(app, event)
            value = app.BottomZboundEditField.Value;
            
            app.bounding_box(3) = value;
        end

        % Value changed function: TopZboundEditField
        function TopZboundEditFieldValueChanged(app, event)
            value = app.TopZboundEditField.Value;
            
            app.bounding_box(4) = value;
        end

        % Value changed function: ForegroundthresholdEditField
        function ForegroundthresholdEditFieldValueChanged(app, event)
            value = app.ForegroundthresholdEditField.Value;
            
            app.depth_thresholds(1) = value;            
        end

        % Value changed function: BackgroundthresholdEditField
        function BackgroundthresholdEditFieldValueChanged(app, event)
            value = app.BackgroundthresholdEditField.Value;
            
            app.depth_thresholds(2) = value;
        end

        % Button pushed function: AnalysesingleplantButton
        function AnalysesingleplantButtonPushed(app, event)
            folder_path = uigetdir('', 'Choose the folder which contains the plant depth images');
            
            drawnow;
            figure(app.GUI)
            
            disp('Analysing')
            
            [pc, measurements] = analyse_plant(folder_path);
            
            disp('Complete')
            
            figure;
            pcshow(pc);
            
            title('Point Cloud')
        end

        % Button pushed function: AnalyseplantsovertimeButton
        function AnalyseplantsovertimeButtonPushed(app, event)
            folder_path = uigetdir('', 'Choose a top level folder which contains the folders for each timepoint');
            
            drawnow;
            figure(app.GUI)
            
            plant_ids = inputdlg('Enter comma-seperated IDs of the plants to analyse, e.g., ID_1,ID_2,ID_3');
            
            plant_ids = split(plant_ids, ',');
            
            for i = 1:numel(plant_ids)                
                plant_id = plant_ids{i};
                
                fprintf('Analysing plant no. %d, ID: %s\n', i, plant_id) 
                
                try
                    analyse_over_time(folder_path, plant_id);
                catch
                    fprintf('Failed to analyse plant %s\n', plant_id);
                end
            end
            
            disp('\nComplete')
        end

        % Button pushed function: AnalyseallplantsovertimeButton
        function AnalyseallplantsovertimeButtonPushed(app, event)
            folder_path = uigetdir('', 'Choose a top level folder which contains the folders for each timepoint');
            
            drawnow;
            figure(app.GUI)
            
            end_paths = get_end_paths(folder_path, {});
            
            for i = 1:numel(end_paths)
                split_path = split(end_paths{i}, '\');
                plant_id = split_path{end};
                
                fprintf('Analysing plant no. %d of %d, ID: %s\n', i, numel(end_paths), plant_id)
                
                try
                    analyse_over_time(folder_path, plant_id);
                catch
                    fprintf('Failed to analyse plant %s\n', plant_id);
                end
            end
            
            disp('Complete')
        end

        % Button pushed function: PlotmeasurementsButton
        function PlotmeasurementsButtonPushed(app, event)
            folder_path = uigetdir('', 'Choose a top level folder which contains the folders for each timepoint');
            
            drawnow;
            figure(app.GUI)
            
            plant_ids = inputdlg('Enter comma-seperated IDs of the plants to analyse, e.g., ID_1,ID_2,ID_3');
            
            plant_ids = split(plant_ids, ',');
            
            for i = 1:numel(plant_ids)
                plant_id = plant_ids{i};
                
                try                
                    measurements_4D = get_4D_measurements(folder_path, plant_id);
                    fields = fieldnames(measurements_4D{1});
                    
                    for j = 1:numel(measurements_4D)                    
                        for k = 1:numel(fields)
                            all_measurements(i, j, k) = measurements_4D{j}.(fields{k});
                        end
                    end
                    
                catch
                    fprintf('No measurement files for plant %s\n', plant_id);
                end
            end
            
            dimensions = size(all_measurements);
            
            for k = 1:dimensions(3)
                figure;
                title(join(split(fields{k}, '_')))
                hold on
                
                for i = 1:dimensions(1)
                    plot(all_measurements(i, :, k));
                end
                
                legend
            end
        end

        % Button pushed function: PlotallmeasurementsButton
        function PlotallmeasurementsButtonPushed(app, event)
            folder_path = uigetdir('', 'Choose a top level folder which contains the folders for each timepoint');
            
            drawnow;
            figure(app.GUI)
            
            end_paths = get_end_paths(folder_path, {});
            
            seen_ids = {};
            
            for i = 1:numel(end_paths)
                split_path = split(end_paths{i}, '\');
                plant_id = split_path{end};
                
                if ~any(strcmp(seen_ids, plant_id))
                    try                
                        measurements_4D = get_4D_measurements(folder_path, plant_id);
                        fields = fieldnames(measurements_4D{1});
                        
                        for j = 1:numel(measurements_4D)                    
                            for k = 1:numel(fields)
                                all_measurements(i, j, k) = measurements_4D{j}.(fields{k});
                            end
                        end
                        
                    catch
                        fprintf('No measurement files for plant %s\n', plant_id);
                    end
                    
                    seen_ids{end + 1} = plant_id;
                end
            end
            
            dimensions = size(all_measurements);
            
            for k = 1:dimensions(3)
                figure;
                title(join(split(fields{k}, '_')))
                hold on
                
                for i = 1:dimensions(1)
                    plot(all_measurements(i, :, k));
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure('Visible', 'off');
            app.GUI.Name = 'UI Figure';
            app.CreateparameterfilesButton = uibutton(app.GUI, 'push');
            app.CreateparameterfilesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateparameterfilesButtonPushed, true);
            app.CreateparameterfilesButton.Text = 'Create parameter files';
            app.ParameterDropDownLabel = uilabel(app.GUI);
            app.ParameterDropDownLabel.Visible = 'off';
            app.ParameterDropDownLabel.Text = 'Parameter';
            app.ParameterDropDown = uidropdown(app.GUI);
            app.ParameterDropDown.Items = {'Image Height', 'Image Width', 'Number of views', 'Vertical axis', 'Remove plane', 'Remove pot', 'Depth thresholds', 'Bounding box', 'Save point cloud', 'Save measurements'};
            app.ParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterDropDownValueChanged, true);
            app.ParameterDropDown.Visible = 'off';
            app.ParameterDropDown.Value = 'Image Height';
            app.ImageHeightEditFieldLabel = uilabel(app.GUI);
            app.ImageHeightEditFieldLabel.Visible = 'off';
            app.ImageHeightEditFieldLabel.Text = 'Image Height';
            app.ImageHeightEditField = uieditfield(app.GUI, 'numeric');
            app.ImageHeightEditField.Limits = [1 Inf];
            app.ImageHeightEditField.ValueChangedFcn = createCallbackFcn(app, @ImageHeightEditFieldValueChanged, true);
            app.ImageHeightEditField.Visible = 'off';
            app.ImageHeightEditField.Value = 424;
            app.RightboundLabel = uilabel(app.GUI);
            app.RightboundLabel.Visible = 'off';
            app.RightboundLabel.Text = 'Right bound';
            app.RightboundEditField = uieditfield(app.GUI, 'numeric');
            app.RightboundEditField.ValueChangedFcn = createCallbackFcn(app, @RightboundEditFieldValueChanged, true);
            app.RightboundEditField.Visible = 'off';
            app.BelowplantboundLabel = uilabel(app.GUI);
            app.BelowplantboundLabel.Visible = 'off';
            app.BelowplantboundLabel.Text = 'Below plant bound';
            app.BottomZboundEditField = uieditfield(app.GUI, 'numeric');
            app.BottomZboundEditField.ValueChangedFcn = createCallbackFcn(app, @BottomZboundEditFieldValueChanged, true);
            app.BottomZboundEditField.Visible = 'off';
            app.AboveplantboundLabel = uilabel(app.GUI);
            app.AboveplantboundLabel.Visible = 'off';
            app.AboveplantboundLabel.Text = 'Above plant bound';
            app.TopZboundEditField = uieditfield(app.GUI, 'numeric');
            app.TopZboundEditField.ValueChangedFcn = createCallbackFcn(app, @TopZboundEditFieldValueChanged, true);
            app.TopZboundEditField.Visible = 'off';
            app.TextparamEditFieldLabel = uilabel(app.GUI);
            app.TextparamEditFieldLabel.Visible = 'off';
            app.TextparamEditFieldLabel.Text = 'Text param';
            app.TextparamEditField = uieditfield(app.GUI, 'text');
            app.TextparamEditField.ValueChangedFcn = createCallbackFcn(app, @TextparamEditFieldValueChanged, true);
            app.TextparamEditField.Visible = 'off';
            app.GenerateparameterfilesButton = uibutton(app.GUI, 'push');
            app.GenerateparameterfilesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateparameterfilesButtonPushed, true);
            app.GenerateparameterfilesButton.Visible = 'off';
            app.GenerateparameterfilesButton.Text = 'Generate parameter files ';
            app.booleanDropDownLabel = uilabel(app.GUI);
            app.booleanDropDownLabel.Visible = 'off';
            app.booleanDropDownLabel.Text = 'boolean';
            app.booleanDropDown = uidropdown(app.GUI);
            app.booleanDropDown.Items = {'true', 'false'};
            app.booleanDropDown.ValueChangedFcn = createCallbackFcn(app, @booleanDropDownValueChanged, true);
            app.booleanDropDown.Visible = 'off';
            app.booleanDropDown.Value = 'true';
            app.LeftboundLabel = uilabel(app.GUI);
            app.LeftboundLabel.Visible = 'off';
            app.LeftboundLabel.Text = 'Left bound';
            app.LeftboundEditField = uieditfield(app.GUI, 'numeric');
            app.LeftboundEditField.ValueChangedFcn = createCallbackFcn(app, @LeftboundEditFieldValueChanged, true);
            app.LeftboundEditField.Visible = 'off';
            app.ViewdepthimageforboundingboxButton = uibutton(app.GUI, 'push');
            app.ViewdepthimageforboundingboxButton.ButtonPushedFcn = createCallbackFcn(app, @ViewdepthimageforboundingboxButtonPushed, true);
            app.ViewdepthimageforboundingboxButton.Text = 'View depth image for bounding box';
            app.AnalyseplantsovertimeButton = uibutton(app.GUI, 'push');
            app.AnalyseplantsovertimeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseplantsovertimeButtonPushed, true);
            app.AnalyseplantsovertimeButton.Text = 'Analyse plant(s) over time';
            app.AnalyseallplantsovertimeButton = uibutton(app.GUI, 'push');
            app.AnalyseallplantsovertimeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseallplantsovertimeButtonPushed, true);
            app.AnalyseallplantsovertimeButton.Text = 'Analyse all plants over time';
            app.PlotmeasurementsButton = uibutton(app.GUI, 'push');
            app.PlotmeasurementsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotmeasurementsButtonPushed, true);
            app.PlotmeasurementsButton.Text = 'Plot measurements';
            app.PlotallmeasurementsButton = uibutton(app.GUI, 'push');
            app.PlotallmeasurementsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotallmeasurementsButtonPushed, true);
            app.PlotallmeasurementsButton.Text = 'Plot all measurements';
            app.AnalysesingleplantButton = uibutton(app.GUI, 'push');
            app.AnalysesingleplantButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysesingleplantButtonPushed, true);
            app.AnalysesingleplantButton.Text = 'Analyse single plant';
            app.ViewpointcloudButton = uibutton(app.GUI, 'push');
            app.ViewpointcloudButton.ButtonPushedFcn = createCallbackFcn(app, @ViewpointcloudButtonPushed, true);
            app.ViewpointcloudButton.Text = 'View point cloud';
            app.ViewdepthimageButton = uibutton(app.GUI, 'push');
            app.ViewdepthimageButton.ButtonPushedFcn = createCallbackFcn(app, @ViewdepthimageButtonPushed, true);
            app.ViewdepthimageButton.Text = 'View depth image';
            app.ForegroundthresholdEditFieldLabel = uilabel(app.GUI);
            app.ForegroundthresholdEditFieldLabel.Visible = 'off';
            app.ForegroundthresholdEditFieldLabel.Text = 'Foreground threshold';
            app.ForegroundthresholdEditField = uieditfield(app.GUI, 'numeric');
            app.ForegroundthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ForegroundthresholdEditFieldValueChanged, true);
            app.ForegroundthresholdEditField.Visible = 'off';
            app.ForegroundthresholdEditField.Value = 250;
            app.BackgroundthresholdEditFieldLabel = uilabel(app.GUI);
            app.BackgroundthresholdEditFieldLabel.Visible = 'off';
            app.BackgroundthresholdEditFieldLabel.Text = 'Background threshold';
            app.BackgroundthresholdEditField = uieditfield(app.GUI, 'numeric');
            app.BackgroundthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @BackgroundthresholdEditFieldValueChanged, true);
            app.BackgroundthresholdEditField.Visible = 'off';
            app.BackgroundthresholdEditField.Value = 1800;
            app.CancelButton = uibutton(app.GUI, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Visible = 'off';
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plant_analysis_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end